classdef Ignition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainCalculationsTab             matlab.ui.container.Tab
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SaveFileNameEditField_2         matlab.ui.control.EditField
        SaveFileNameEditField_2Label    matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        GeneralInputsPanel              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MachNumberPostDiffuserEditField_2  matlab.ui.control.NumericEditField
        MachNumberPostDiffuserEditField_2Label  matlab.ui.control.Label
        gminLabel                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        LsLabel                         matlab.ui.control.Label
        kmhrLabel                       matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        JkgKLabel                       matlab.ui.control.Label
        NumberofPlotPointsEditField     matlab.ui.control.NumericEditField
        NumberofPlotPointsEditFieldLabel  matlab.ui.control.Label
        FuelFlowRateEditField           matlab.ui.control.NumericEditField
        FuelFlowRateEditFieldLabel      matlab.ui.control.Label
        TurbineDiameterEditField        matlab.ui.control.NumericEditField
        TurbineDiameterEditFieldLabel   matlab.ui.control.Label
        NozzleDiameterEditField         matlab.ui.control.NumericEditField
        NozzleDiameterEditFieldLabel    matlab.ui.control.Label
        FlameHolderHeightEditField_2    matlab.ui.control.NumericEditField
        FlameHolderHeightEditField_2Label  matlab.ui.control.Label
        FlameHolderHalfAngleEditField_2  matlab.ui.control.NumericEditField
        FlameHolderHalfAngleEditField_2Label  matlab.ui.control.Label
        StraightSectionLengthEditField_2  matlab.ui.control.NumericEditField
        StraightSectionLengthEditField_2Label  matlab.ui.control.Label
        DiffuserLengthEditField_2       matlab.ui.control.NumericEditField
        DiffuserLengthEditField_2Label  matlab.ui.control.Label
        TurbineBladeLengthEditField_3   matlab.ui.control.NumericEditField
        TurbineBladeLengthEditField_3Label  matlab.ui.control.Label
        MassFlowInEditField             matlab.ui.control.NumericEditField
        MassFlowInEditFieldLabel        matlab.ui.control.Label
        IgnitionTimeParameterEditField_2  matlab.ui.control.NumericEditField
        IgnitionTimeParameterEditField_2Label  matlab.ui.control.Label
        BlowoutVelocityParameterEditField_2  matlab.ui.control.NumericEditField
        BlowoutVelocityParameterEditField_2Label  matlab.ui.control.Label
        ExitVelocityEditField           matlab.ui.control.NumericEditField
        ExitVelocitykmhrLabel           matlab.ui.control.Label
        EGTEditField                    matlab.ui.control.NumericEditField
        EGTCLabel                       matlab.ui.control.Label
        AirDensityEditField             matlab.ui.control.NumericEditField
        AirDensitykgm3Label             matlab.ui.control.Label
        SpecificGasConstantEditField    matlab.ui.control.NumericEditField
        SpecificGasConstantEditFieldLabel  matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        UIAxes2                         matlab.ui.control.UIAxes
        CombustionAnalysisTab_2         matlab.ui.container.Tab
        EvaluateButton                  matlab.ui.control.Button
        CombustionResultsPanel          matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        kPaLabel                        matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        f_stoichiometricEditField       matlab.ui.control.NumericEditField
        f_stoichiometricEditFieldLabel  matlab.ui.control.Label
        f_actualEditField               matlab.ui.control.NumericEditField
        f_actualEditFieldLabel          matlab.ui.control.Label
        PhiEditField                    matlab.ui.control.NumericEditField
        PhiLabel                        matlab.ui.control.Label
        AfterburnerPressureEditField    matlab.ui.control.NumericEditField
        AfterburnerPressureEditFieldLabel  matlab.ui.control.Label
        AfterburnerTemperatureEditField  matlab.ui.control.NumericEditField
        AfterburnerTemperatureEditFieldLabel  matlab.ui.control.Label
        CombustionParametersPanel       matlab.ui.container.Panel
        AfterburnerCombustionPanel      matlab.ui.container.Panel
        GridLayout3_2                   matlab.ui.container.GridLayout
        Element4EditField_2             matlab.ui.control.EditField
        Element4EditField_2Label        matlab.ui.control.Label
        Element3EditField_2             matlab.ui.control.EditField
        Element3EditField_2Label        matlab.ui.control.Label
        Element2EditField_2             matlab.ui.control.EditField
        Element2EditField_2Label        matlab.ui.control.Label
        Element1EditField_2             matlab.ui.control.EditField
        Element1EditField_2Label        matlab.ui.control.Label
        MolecularWeight4EditField_2     matlab.ui.control.NumericEditField
        MolecularWeight4EditField_2Label  matlab.ui.control.Label
        MolecularWeight3EditField_2     matlab.ui.control.NumericEditField
        MolecularWeight3EditField_2Label  matlab.ui.control.Label
        MolecularWeight2EditField_2     matlab.ui.control.NumericEditField
        MolecularWeight2EditField_2Label  matlab.ui.control.Label
        MolecularWeight1EditField_2     matlab.ui.control.NumericEditField
        MolecularWeight1EditField_2Label  matlab.ui.control.Label
        EngineCombustionPanel           matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        FuelLabel                       matlab.ui.control.Label
        Image_5                         matlab.ui.control.Image
        FinalResultsTab                 matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UnitConversionsTab              matlab.ui.container.Tab
        Image_6                         matlab.ui.control.Image
        SettingsTab                     matlab.ui.container.Tab
        JankButton                      matlab.ui.control.Button
        PlotColorPanel                  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        NozzleDropDown                  matlab.ui.control.DropDown
        NozzleDropDownLabel             matlab.ui.control.Label
        FlameHolderDropDown             matlab.ui.control.DropDown
        FlameHolderDropDownLabel        matlab.ui.control.Label
        CombustionChamberDropDown       matlab.ui.control.DropDown
        CombustionChamberDropDownLabel  matlab.ui.control.Label
        StraightSectionDropDown         matlab.ui.control.DropDown
        StraightSectionDropDownLabel    matlab.ui.control.Label
        DiffuserDropDown                matlab.ui.control.DropDown
        DiffuserDropDownLabel           matlab.ui.control.Label
        EngineDropDown                  matlab.ui.control.DropDown
        EngineDropDownLabel             matlab.ui.control.Label
        ThemeDropDown                   matlab.ui.control.DropDown
        ThemeDropDownLabel              matlab.ui.control.Label
        Image_7                         matlab.ui.control.Image
    end

    
    properties (Access = private)
            %Reset and Stop button stuff
            %OriginalVals;
            %Stop = False;

            % Geometry & Design Variables
            d_turbine;  
            d_nozzle;           
            turbblade;          
            diff_length;      
            n_points;           
            D;                 
            theta;             
            l_diff;
            straight;
            rab;
            r_outer;
            testimage;

            
            % Thermodynamic & Flow Parameters
            Tc;                 
            BVP;                
            gamma;              
            R;                  
            rho_air;           
            T2;                
            u2;
            f_act;
            f_st;
            
            % Combustion and Mass Flow Variables
            mdot_in;            
            mdot_fuel;          
            mdot_air;          
            P3;
            Cp;
            T3;
            M3;
            Tpost;
            Ppost;
            Vlmax;
            phi;
            mixArray1;
            mixArray2;
    end
    
    methods (Access = private)
        
       function aas = aastar(app,M,gamma)
            aas = (1/M)*(2/(gamma+1)*(1+((gamma-1)/2)*M^2))^((gamma+1)/(2*(gamma-1)));
        end
        
        function TT0 = stagtemp(app,M,gamma)
            TT0 = (1+((gamma-1)/2)*M^2)^-1;
        end

        function volume = SoundVolume(app,volume) %Little haha funny func. dw about it.
            % Programmed and Copyright by Yair M. Altman: altmany(at)gmail.com
            % $Revision: 1.3 $  $Date: 2014/05/09 18:43:38 $
            % Check for available Java/AWT (not sure if Swing is really needed so let's just check AWT)
            if ~usejava('awt')
                error('YMA:SoundVolume:noJava','SoundVolume only works on Matlab envs that run on java');
            end
            % Args check
            if nargin && (~isnumeric(volume) || length(volume)~=1 || volume<0 || volume>1)
                error('YMA:SoundVolume:badVolume','Volume value must be a scalar number between 0.0 and 1.0')
            end
            % Loop over all the system's MixerInfo objects to find the speaker port
            % Note: we should have used line=AudioSystem.getLine(Port.Info.SPEAKER) directly, as in http://forums.sun.com/thread.jspa?messageID=10736264#10736264
            % ^^^^  but unfortunately Matlab prevents using Java Interfaces and/or classnames containing a period
            import javax.sound.sampled.*
            mixerInfos = AudioSystem.getMixerInfo;
            foundFlag = 0;
            for mixerIdx = 1 : length(mixerInfos)
                if foundFlag,  break;  end
                % ports = AudioSystem.getMixer(mixerInfos(mixerIdx)).getTargetLineInfo;  % => not allowed in Matlab for some reason (bug)
                ports = getTargetLineInfo(AudioSystem.getMixer(mixerInfos(mixerIdx)));
                for portIdx = 1 : length(ports)
                    port = ports(portIdx);
                    try
                        portName = port.getName;  % better
                    catch
                        portName = port.toString; % sub-optimal
                    end
                    if ~isempty(strfind(lower(char(portName)),'speaker'))
                        foundFlag = 1;
                        break;
                    end
                end
            end
            if ~foundFlag
                error('YMA:SoundVolume:noSpeakerPort','Speaker port not found');
            end

            % Get and open the speaker port's Line object
            line = AudioSystem.getLine(port);
            line.open();
            % Loop over all the Line's controls to find the Volume control
            % Note: we should have used ctrl=line.getControl(FloatControl.Type.VOLUME) directly, as in http://forums.sun.com/thread.jspa?messageID=10736264#10736264
            % ^^^^  but unfortunately Matlab prevents using Java Interfaces and/or classnames containing a period
            ctrls = line.getControls;
            foundFlag = 0;
            for ctrlIdx = 1 : length(ctrls)
                ctrl = ctrls(ctrlIdx);
                ctrlType = ctrls(ctrlIdx).getType;
                try
                    ctrlType = char(ctrlType);
                catch
                    % Solves some edge-cases - D. Nguyen
                    ctrlType = char(ctrlType.toString);
                end
                if ~isempty(strfind(lower(ctrlType),'volume'))
                    foundFlag = 1;
                    break;
                end
            end
            if ~foundFlag
                error('YMA:SoundVolume:noVolumeControl','Speaker volume control not found');
            end

            % Get or set the volume value according to the user request
            oldValue = ctrl.getValue;
            if nargin
                ctrl.setValue(volume);
            end
            if nargout
                volume = oldValue;
            end

            end  % SoundVolume
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Ignition';
            data = load('testimage.mat');
            app.testimage = data.testimage;
            
            %sets initial values (suggestions) and saves values in case
            %things are reset (will implement later along with a reset
            %button)
            app.GammaEditField.Value = 1.4;
            app.SpecificGasConstantEditField.Value = 287;
            app.AirDensityEditField.Value = 1.225;
            app.EGTEditField.Value = 800;
            app.ExitVelocityEditField.Value = 1700;
            app.BlowoutVelocityParameterEditField_2.Value = 0.9516;
            app.IgnitionTimeParameterEditField_2.Value = 3.46e-4;
            app.MassFlowInEditField.Value = 300;
            app.TurbineBladeLengthEditField_3.Value = 16e-3;
            app.DiffuserLengthEditField_2.Value = 0.120;
            app.StraightSectionLengthEditField_2.Value = 0.1;
            app.FlameHolderHalfAngleEditField_2.Value = 15;
            app.FlameHolderHeightEditField_2.Value = 0.25;
            app.NozzleDiameterEditField.Value = 51e-3;
            app.TurbineDiameterEditField.Value = 71e-3;
            app.FuelFlowRateEditField.Value = 540;
            app.NumberofPlotPointsEditField.Value = 1000;

            % For a reset button. Not implemented yet
            % app.OriginialVals.in1 = app.GammaEditField.Value;
            % app.OriginialVals.in2 = app.SpecificGasConstantEditField.Value;
            % app.OriginialVals.in3 = app.AirDensityEditField.Value;
            % app.OriginialVals.in4 = app.EGTEditField.Value;
            % app.OriginialVals.in5 = app.ExitVelocityEditField.Value;
            % app.OriginialVals.in6 = app.BlowoutVelocityParameterEditField_2.Value;
            % app.OriginialVals.in7 = app.IgnitionTimeParameterEditField_2.Value;
            % app.OriginialVals.in8 = app.MassFlowInEditField.Value;
            % app.OriginialVals.in9 = app.TurbineBladeLengthEditField_3.Value;
            % app.OriginialVals.in10 = app.DiffuserLengthEditField_2.Value;
            % app.OriginialVals.in11 = app.StraightSectionLengthEditField_2.Value;
            % app.OriginialVals.in12 = app.FlameHolderHalfAngleEditField_2.Value;
            % app.OriginialVals.in13 = app.FlameHolderHeightEditField_2.Value;
            % app.OriginialVals.in14 = app.NozzleDiameterEditField.Value;
            % app.OriginialVals.in15 = app.TurbineDiameterEditField.Value;
            % app.OriginialVals.in16 = app.FuelFlowRateEditField.Value;
            % app.OriginialVals.in17 = app.NumberofPlotPointsEditField.Value;

        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            app.gamma = app.GammaEditField.Value;
            
        end

        % Value changed function: SpecificGasConstantEditField
        function SpecificGasConstantEditFieldValueChanged(app, event)
            app.R = app.SpecificGasConstantEditField.Value;
            
        end

        % Value changed function: AirDensityEditField
        function AirDensityEditFieldValueChanged(app, event)
            app.rho_air = app.AirDensityEditField.Value;
            
        end

        % Value changed function: EGTEditField
        function EGTEditFieldValueChanged(app, event)
            app.T2 = app.EGTEditField.Value;
            
        end

        % Value changed function: ExitVelocityEditField
        function ExitVelocityEditFieldValueChanged(app, event)
            app.u2 = app.ExitVelocityEditField.Value;
            
        end

        % Value changed function: BlowoutVelocityParameterEditField_2
        function BlowoutVelocityParameterEditField_2ValueChanged(app, event)
            app.BVP = app.BlowoutVelocityParameterEditField_2.Value;
            
        end

        % Value changed function: IgnitionTimeParameterEditField_2
        function IgnitionTimeParameterEditField_2ValueChanged(app, event)
            app.Tc = app.IgnitionTimeParameterEditField_2.Value;
            
        end

        % Value changed function: TurbineBladeLengthEditField_3
        function TurbineBladeLengthEditField_3ValueChanged(app, event)
            app.turbblade = app.TurbineBladeLengthEditField_3.Value;
        end

        % Value changed function: DiffuserLengthEditField_2
        function DiffuserLengthEditField_2ValueChanged(app, event)
            app.diff_length = app.DiffuserLengthEditField_2.Value;
            
        end

        % Value changed function: StraightSectionLengthEditField_2
        function StraightSectionLengthEditField_2ValueChanged(app, event)
            app.straight = app.StraightSectionLengthEditField_2.Value;
            
        end

        % Value changed function: FlameHolderHalfAngleEditField_2
        function FlameHolderHalfAngleEditField_2ValueChanged(app, event)
            app.theta = app.FlameHolderHalfAngleEditField_2.Value;
            
        end

        % Value changed function: FlameHolderHeightEditField_2
        function FlameHolderHeightEditField_2ValueChanged(app, event)
            app.D = app.FlameHolderHeightEditField_2.Value;
            
        end

        % Value changed function: NumberofPlotPointsEditField
        function NumberofPlotPointsEditFieldValueChanged(app, event)
            app.n_points = app.NumberofPlotPointsEditField.Value;
            
        end

        % Value changed function: SaveFileNameEditField_2
        function SaveFileNameEditField_2ValueChanged(app, event)
            app.savename = app.SaveFileNameEditField_2.Value;
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            savename = 'diffuser_geometry.csv';
            csvwrite(savename, data);
            %Would like to also tell the user where the file has been saved
            %to / let them go into a file browser at some point
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            %unimplemented stop button
            %app.Stop = false;
            %while app.Stop == false
                
                %lets the user know things are happening
                app.RunButton_2.Text = 'Processing...';
                drawnow;
                pause(0.25);
                %For some reason, the code doesn't want to run unless I
                %redefine everything (all the vars) again in the run button
                %code. Even though each edit field has it's own command, this
                %thing still won't work. So they'll be redesigned below

                app.gamma = app.GammaEditField.Value;
                app.R = app.SpecificGasConstantEditField.Value;
                app.rho_air = app.AirDensityEditField.Value;
                app.T2 = app.EGTEditField.Value;
                app.u2 = app.ExitVelocityEditField.Value;
                app.BVP = app.BlowoutVelocityParameterEditField_2.Value;
                app.Tc = app.IgnitionTimeParameterEditField_2.Value;
                app.turbblade = app.TurbineBladeLengthEditField_3.Value;
                app.diff_length = app.DiffuserLengthEditField_2.Value;
                app.straight = app.StraightSectionLengthEditField_2.Value;
                app.theta = app.FlameHolderHalfAngleEditField_2.Value;
                app.D = app.FlameHolderHeightEditField_2.Value;
                app.n_points = app.NumberofPlotPointsEditField.Value;
                app.d_nozzle = app.NozzleDiameterEditField.Value;
                app.d_turbine = app.TurbineDiameterEditField.Value;
                app.mdot_in = app.MassFlowInEditField.Value;
                app.mdot_fuel = app.FuelFlowRateEditField.Value;

                pie = double(pi);
                A1 = (pie*app.d_turbine^2)/4;
                A2 = (pie*app.d_nozzle^2)/4;

                %Convert from C to K
                app.T2 = app.T2 + 273.15;

                %Convert from km/h to m/s
                app.u2 = app.u2*1000*(1/3600);

                M2 = app.u2/sqrt(app.gamma*app.R*app.T2);

                %Isentropic Area Relations
                a2astar = app.aastar(M2,app.gamma);
                Astar = A2/a2astar;

                %Finds M1 using area relations. Outputs a vector but answer takes only
                %real, subsonic answers (see M1 = M1(1))
                syms M1
                a1astar = app.aastar(M1,app.gamma);
                M1 = vpasolve(A1/Astar == a1astar,M1);
                M1 = M1(1);

                %Find the stagnation temperature for the nozzle and then finds temp at
                %section '1'
                T2T0 = app.stagtemp(M2,app.gamma);

                T0 = app.T2/T2T0;

                T1T0 = app.stagtemp(M1,app.gamma);
                T1 = T1T0*T0;

                %calculate velocity coming out of the turbine => goes into the afterburner
                %diffuser
                u1 = M1*sqrt(app.gamma*app.R*T1);


                %Need to make an initial assumption to complete the diffuser/ flame holder
                %sections. We will take diameter of the afterburner to be 91mm. Will be set
                %in relation to the turbine to make it easier to recalculate (less
                %variables to reinput). Also need to pass in an ignition time parameter.
                %Initial guess was collected from Table 2 in the AB report we're following.
                %Will likely be adusted later.
                
                %Diffuser Geometry
                rab = app.d_turbine/2 + 0.01;
                
                %This needs to be added as an edit field
                app.l_diff = 0.025; %r_diff/tand(theta);%not sure what to
                %do with this. it's being weird and im not sure which one
                %is correct
                app.Vlmax = app.BVP*(rab/app.Tc); %may need to be set by user??

                app.Cp = (app.gamma*app.R)/(app.gamma-1);
                app.T3 = T1 + (u1^2/(2*app.Cp)) - (app.Vlmax^2/(2*app.Cp));

                app.M3 = app.Vlmax/(sqrt(app.gamma*app.R*app.T3));
                %generally speaking, the M <= 0.4. Not sure I want to run a
                %check atm. Might implement later

                % Define input parameters
                r1_inner = app.turbblade;  % Inner inlet radius (meters)
                r3_inner = 0; % Inner outlet radius (meters)

                r1_outer = app.d_turbine/2;  % Outer inlet radius (meters)
                r3_outer = rab; % Outer outlet radius (meters)

                % Generate x-coordinates (axial positions)
                x = linspace(0, app.diff_length, app.n_points);

                % Compute inner and outer radius values assuming linear increase
                r_inner = linspace(r1_inner, r3_inner, app.n_points);
                r_outer = linspace(r1_outer, r3_outer, app.n_points);

                % Compute corresponding cross-sectional areas
                %A_inner = pi32 * r_inner.^2; % Inner area (hollow region)
                %A_outer = pi32 * r_outer.^2; % Outer total area

                %A_flow = A_outer - A_inner; % Flow area (actual open space)
                %%might be unused and leftover from earlier versions of the
                %%code. Could be used later to find the local values for a
                %%plot (temp, pres, mach, etc)

                % Combine into a matrix for CSV output
                %data = [x' r_inner' r_outer' A_inner' A_outer' A_flow'];

                app.D = app.D*0.0254;%Converts from in to m
                %r_diff = app.D/2; %Unused???

                %Nozzle Calculations
                %for now, using Tpost = 2600K Ppost = 220kPa
                app.Tpost = 2600;
                app.Ppost = 220000;
                Mpost = app.Vlmax/(sqrt(app.gamma*app.R*app.Tpost));
                A_comb_chamber = pi*(r3_outer(end)^2);
                %For converging nozzle, assume Mexit = 1 (ideal case)
                Mexit = 1;
                Aexit = ((Mpost*A_comb_chamber)/Mexit)*sqrt(((1+((app.gamma-1)/2)*Mexit^2)/(1+((app.gamma-1)/2)*Mpost^2))^((app.gamma+1)/(app.gamma-1)));
                rexit = sqrt(Aexit/pi);


                % Plot geometry
                %plot color will be fully implemented later
                plotcolord = 'r';
                plotcolore = 'm';
                plotcolorf = 'g';

                cla(app.UIAxes2);
                hold(app.UIAxes2, 'on');

                %Diffuser
                plot(app.UIAxes2,[-0.2,0.5], [0,0], 'k--', 'LineWidth', 1.5); % Horizontal x-axis as white dashed line
                plot(app.UIAxes2,x, r_outer, 'Color', plotcolord, 'LineWidth', 2);
                plot(app.UIAxes2,x, -r_outer, 'Color',plotcolord, 'LineWidth', 2);
                plot(app.UIAxes2,x, r_inner, 'Color',plotcolord, 'LineWidth', 2);
                plot(app.UIAxes2,x, -r_inner, 'Color',plotcolord, 'LineWidth', 2);


                %Straight expanding section
                plot(app.UIAxes2,[app.diff_length, app.diff_length + app.straight], [r3_outer, r3_outer], 'Color', plotcolore, 'LineWidth', 2);
                plot(app.UIAxes2,[app.diff_length, app.diff_length + app.straight], [-r3_outer, -r3_outer], 'Color', plotcolore, 'LineWidth', 2);


                %Flame holder
                flame_holder_start = [0.22, 0.025];

                x_v1 = flame_holder_start(1) + app.l_diff * cosd(app.theta);
                y_v1 = flame_holder_start(2) + app.l_diff * sind(app.theta);
                x_v2 = flame_holder_start(1) + app.l_diff * cosd(app.theta);
                y_v2 = flame_holder_start(2) - app.l_diff * sind(app.theta);

                plot(app.UIAxes2,[flame_holder_start(1), x_v1], [flame_holder_start(2), y_v1], 'Color', plotcolorf, 'LineWidth', 2);
                plot(app.UIAxes2,[flame_holder_start(1), x_v2], [flame_holder_start(2), y_v2], 'Color', plotcolorf, 'LineWidth', 2);
                plot(app.UIAxes2,[flame_holder_start(1), x_v1], [-flame_holder_start(2), -y_v1], 'Color', plotcolorf, 'LineWidth', 2);
                plot(app.UIAxes2,[flame_holder_start(1), x_v2], [-flame_holder_start(2), -y_v2], 'Color', plotcolorf, 'LineWidth', 2);


                %Engine
                % Define the coordinates for the rectangle
                x_coords = [-0.172, 0];  % x-coordinates for the left and right sides
                y_coords_top = [0.055, 0.055];  % y-coordinates for the top side
                y_coords_bottom = [-0.055, -0.055];  % y-coordinates for the bottom side

                plot(app.UIAxes2, x_coords, y_coords_top, 'k','LineWidth',2);  % Top edge
                plot(app.UIAxes2, x_coords, y_coords_bottom, 'k','LineWidth',2);  % Bottom edge
                plot(app.UIAxes2, [-0.172, -0.172], [-0.055, 0.055], 'k','LineWidth',2);  % Left edge
                plot(app.UIAxes2, [0, 0], [-0.055, 0.055], 'k','LineWidth',2);  % Right edge

                %Combustion Chamber
                x_combustion = linspace(0.22, 0.22 + (app.straight * 1.5), 100); % Define x range
                y_combustion = ones(size(x_combustion)) * r3_outer; % Constant y-value

                plot(app.UIAxes2,x_combustion, y_combustion, 'b','LineWidth',2); % Plot the combustion chamber section
                plot(app.UIAxes2,x_combustion, -y_combustion, 'b','LineWidth',2); % Plot the combustion chamber section


                %Nozzle
                %Should allow the user to choose between C and CND. For the
                %C nozzle, assume linear taper down to final area. For the
                %CND nozzle, also allow the user to choose between linear
                %or bell shaped nozzle. For now only a single C nozzle is
                %implemented
                %Nozzle
                x_start = 0.37; % Define starting x position
                x_nozzle = linspace(x_start, x_start + 0.1, 50); % Nozzle length
                y_nozzle = linspace(r_outer(end), rexit, 50);  % Linear taper from initial to final radius
                plot(app.UIAxes2,x_nozzle, -y_nozzle, 'c', 'LineWidth', 2);
                plot(app.UIAxes2,x_nozzle, y_nozzle, 'c', 'LineWidth', 2);


                grid(app.UIAxes2,'on');
                xlabel(app.UIAxes2,'Axial Position (m)');
                ylabel(app.UIAxes2,'Radius (m)');
                title(app.UIAxes2,'Afterburner Geometry');

                %legend
                leg_diff = plot(app.UIAxes2,NaN,NaN,'Color',plotcolord,'LineWidth',2);
                leg_str = plot(app.UIAxes2,NaN,NaN,'Color',plotcolore,'LineWidth',2);
                leg_fl = plot(app.UIAxes2,NaN,NaN,'Color',plotcolorf,'LineWidth',2);
                leg_en = plot(app.UIAxes2,NaN,NaN,'Color','k','LineWidth',2);
                leg_comb = plot(app.UIAxes2,NaN,NaN,'Color','b','LineWidth',2);
                leg_noz = plot(app.UIAxes2,NaN,NaN,'Color','c','LineWidth',2);
                legend(app.UIAxes2,[leg_en,leg_diff,leg_str,leg_fl,leg_comb,leg_noz],'Engine','Diffuser','Straight Section','Flame Holder','Combustion Chamber','Nozzle','Location', 'northwest');

                hold(app.UIAxes2,'off');
                app.RunButton_2.Text = 'Completed';
            %end

        end

        % Value changed function: NozzleDiameterEditField
        function NozzleDiameterEditFieldValueChanged(app, event)
            app.d_nozzle = app.NozzleDiameterEditField.Value;
            
        end

        % Value changed function: TurbineDiameterEditField
        function TurbineDiameterEditFieldValueChanged(app, event)
             app.d_turbine = app.TurbineDiameterEditField.Value;
            
        end

        % Value changed function: MassFlowInEditField
        function MassFlowInEditFieldValueChanged(app, event)
            app.mdot_in = app.MassFlowInEditField.Value;
            
        end

        % Value changed function: FuelFlowRateEditField
        function FuelFlowRateEditFieldValueChanged(app, event)
            app.mdot_fuel = app.FuelFlowRateEditField.Value;
            
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            app.EvaluateButton.Text = 'Processing...';
            drawnow;
            pause(0.25);
            
            app.mdot_in = app.mdot_in*10^-3; %m^3/s
            app.mdot_fuel = app.mdot_fuel*(1/1000)*(1/60); %kg/s
            mdot_total = app.rho_air*app.mdot_in;
            app.mdot_air = mdot_total - app.mdot_fuel;
            
            app.f_act = app.mdot_fuel/app.mdot_air;

            % Number passing into the toolbox needs to be an actual value. 
            % Comes out as a weird symbolic thing from earlier calculations
            app.T3 = eval(app.T3);
            
            MW_carbon = 12.011;
            MW_oxygen = 15.999;
            MW_nitrogen = 14.007;
            MW_hydrogen = 1.0078;
            MW_fuel = 12*MW_carbon + 24*MW_hydrogen;
            MW_oxidizer = 2*MW_oxygen + 3.74*2*MW_nitrogen; %Not sure if this is correct (ie supposed to not include the nitrogen)
            app.f_st = MW_fuel/(18*MW_oxidizer);
            
            app.phi = app.f_act/app.f_st;
            app.PhiEditField.Value = app.phi;
            
            %mol_air_new = 18/phi;  %Unused for now? Might want to display
            %to user

            % Import packages
            import combustiontoolbox.databases.NasaDatabase
            import combustiontoolbox.core.*
            import combustiontoolbox.equilibrium.*
            import combustiontoolbox.common.Units

            app.P3 = 2.179;%atm %Pressure needs to be in bar to pass into the combustion toolbox
            app.P3 = app.P3*1.01325;
            app.P3 = Units.convert(2.179,'atm','bar');
            
            DB = NasaDatabase();
            
            system = ChemicalSystem(DB);
            mix = Mixture(system);
            
            set(mix,{'Jet_AbLb'},'fuel', 2.115);
            set(mix,{'O2','N2'},'oxidizer',[31.02,315.969]/31.02); %needs to normalize around 1 mol of O2 (Alberto said this)
            app.mixArray1 = setProperties(mix,'temperature',app.T3,'pressure',app.P3,'equivalenceRatio',1); % 1=> stoiciometric combustion
            
            app.mixArray2 = app.mixArray1.copy(); %For the products
            
            solver = EquilibriumSolver('problemType','HP'); %adiabatic and constant P
            
            combustion = solver.solveArray(app.mixArray2);
            
            app.AfterburnerTemperatureEditField.Value = combustion.T;
            app.AfterburnerPressureEditField.Value = combustion.p;
            app.AfterburnerPressureEditField.Value = Units.convert(app.AfterburnerPressureEditField.Value, 'bar','Pa');
            app.AfterburnerPressureEditField.Value = app.AfterburnerPressureEditField.Value*10^-3;
            app.EvaluateButton.Text = 'Completed';
            
        end

        % Value changed function: AfterburnerTemperatureEditField
        function AfterburnerTemperatureEditFieldValueChanged(app, event)
            
        end

        % Value changed function: AfterburnerPressureEditField
        function AfterburnerPressureEditFieldValueChanged(app, event)

        end

        % Value changed function: PhiEditField
        function PhiEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function Element1DropDownValueChanged(app, event)
            selectedValue = app.Dropdown.Value; % Get the selected value from the dropdown

            % Set the corresponding molecular weight (MW) based on the selection
            switch selectedValue
                case 'Carbon'
                    app.MW1EditField.Value = 12.011; % MW for Carbon
                case 'Oxygen'
                    app.MW1EditField.Value = 15.999; % MW for Oxygen
                case 'Nitrogen'
                    app.MW1EditField.Value = 14.007; % MW for Nitrogen
                case 'Hydrogen'
                    app.MW1EditField.Value = 1.008;  % MW for Hydrogen
                otherwise
                    app.MW1EditField.Value = 0; % Default value (optional)
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % app.GammaEditField.Value = app.OriginialVals.in1;
            % app.SpecificGasConstantEditField.Value = app.OriginialVals.in2;
            % app.AirDensityEditField.Value = app.OriginialVals.in3;
            % app.EGTEditField.Value = app.OriginialVals.in4;
            % app.ExitVelocityEditField.Value = app.OriginialVals.in5;
            % app.BlowoutVelocityParameterEditField_2.Value = app.OriginialVals.in6;
            % app.IgnitionTimeParameterEditField_2.Value = app.OriginialVals.in7;
            % app.MassFlowInEditField.Value = app.OriginialVals.in8;
            % app.TurbineBladeLengthEditField_3.Value = app.OriginialVals.in9;
            % app.DiffuserLengthEditField_2.Value = app.OriginialVals.in10;
            % app.StraightSectionLengthEditField_2.Value = app.OriginialVals.in11l;
            % app.FlameHolderHalfAngleEditField_2.Value = app.OriginialVals.in12;
            % app.FlameHolderHeightEditField_2.Value = app.OriginialVals.in13;
            % app.NozzleDiameterEditField.Value = app.OriginialVals.in14;
            % app.TurbineDiameterEditField.Value = app.OriginialVals.in15;
            % app.FuelFlowRateEditField.Value = app.OriginialVals.in16;
            % app.NumberofPlotPointsEditField.Value = app.OriginialVals.in17;

            %need to also make the button press change the text on the
            %start button
            cla(app.UIAxes2);
            legend(app.UIAxes2,'off');
            app.RunButton_2.Text = 'Start';
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %app.Stop = true;
        end

        % Value changed function: f_actualEditField
        function f_actualEditFieldValueChanged(app, event)
            app.f_actualEditField.Value = app.f_act;
            
        end

        % Value changed function: f_stoichiometricEditField
        function f_stoichiometricEditFieldValueChanged(app, event)
            app.f_stoichiometricEditField.Value = app.f_st;
            
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            %Not implemented yet
            
        end

        % Value changed function: EngineDropDown
        function EngineDropDownValueChanged(app, event)
            
            
        end

        % Value changed function: MachNumberPostDiffuserEditField_2
        function MachNumberPostDiffuserEditField_2ValueChanged(app, event)

            
        end

        % Button pushed function: JankButton
        function JankButtonPushed(app, event)
            screenSize = get(0,'ScreenSize');
            f = figure('Name','','NumberTitle','off','Position',screenSize);
            f.WindowState = 'maximized';
            imshow(app.testimage);

            volume = SoundVolume(app,1.0);
            [y,Fs] = audioread('pipe.mp3');
            sound(y,Fs);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainCalculationsTab = uitab(app.TabGroup);
            app.MainCalculationsTab.Title = 'Main Calculations';
            app.UIAxes2 = uiaxes(app.MainCalculationsTab);
            title(app.UIAxes2, 'Afterburner Geometry', 'Interpreter', 'latex')
            xlabel(app.UIAxes2, 'Axial Distance (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes2, 'Vertical Distance (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes2, 'Z', 'Interpreter', 'latex')
            app.Image_4 = uiimage(app.MainCalculationsTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.GeneralInputsPanel = uipanel(app.MainCalculationsTab);
            app.GeneralInputsPanel.Title = 'General Inputs';
            app.GridLayout2 = uigridlayout(app.GeneralInputsPanel);
            app.GammaEditFieldLabel = uilabel(app.GridLayout2);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.SpecificGasConstantEditFieldLabel = uilabel(app.GridLayout2);
            app.SpecificGasConstantEditFieldLabel.Text = 'Specific Gas Constant';
            app.SpecificGasConstantEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpecificGasConstantEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificGasConstantEditFieldValueChanged, true);
            app.AirDensitykgm3Label = uilabel(app.GridLayout2);
            app.AirDensitykgm3Label.Text = 'Air Density';
            app.AirDensityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AirDensityEditField.ValueChangedFcn = createCallbackFcn(app, @AirDensityEditFieldValueChanged, true);
            app.EGTCLabel = uilabel(app.GridLayout2);
            app.EGTCLabel.Text = 'EGT';
            app.EGTEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EGTEditField.ValueChangedFcn = createCallbackFcn(app, @EGTEditFieldValueChanged, true);
            app.ExitVelocitykmhrLabel = uilabel(app.GridLayout2);
            app.ExitVelocitykmhrLabel.Text = 'Exit Velocity';
            app.ExitVelocityEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ExitVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @ExitVelocityEditFieldValueChanged, true);
            app.BlowoutVelocityParameterEditField_2Label = uilabel(app.GridLayout2);
            app.BlowoutVelocityParameterEditField_2Label.Text = 'Blowout Velocity Parameter';
            app.BlowoutVelocityParameterEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.BlowoutVelocityParameterEditField_2.ValueChangedFcn = createCallbackFcn(app, @BlowoutVelocityParameterEditField_2ValueChanged, true);
            app.IgnitionTimeParameterEditField_2Label = uilabel(app.GridLayout2);
            app.IgnitionTimeParameterEditField_2Label.Text = 'Ignition Time Parameter';
            app.IgnitionTimeParameterEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.IgnitionTimeParameterEditField_2.ValueChangedFcn = createCallbackFcn(app, @IgnitionTimeParameterEditField_2ValueChanged, true);
            app.MassFlowInEditFieldLabel = uilabel(app.GridLayout2);
            app.MassFlowInEditFieldLabel.Text = 'Mass Flow In ';
            app.MassFlowInEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MassFlowInEditField.ValueChangedFcn = createCallbackFcn(app, @MassFlowInEditFieldValueChanged, true);
            app.TurbineBladeLengthEditField_3Label = uilabel(app.GridLayout2);
            app.TurbineBladeLengthEditField_3Label.Text = 'Turbine Blade Length';
            app.TurbineBladeLengthEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.TurbineBladeLengthEditField_3.ValueChangedFcn = createCallbackFcn(app, @TurbineBladeLengthEditField_3ValueChanged, true);
            app.DiffuserLengthEditField_2Label = uilabel(app.GridLayout2);
            app.DiffuserLengthEditField_2Label.Text = 'Diffuser Length';
            app.DiffuserLengthEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.DiffuserLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @DiffuserLengthEditField_2ValueChanged, true);
            app.StraightSectionLengthEditField_2Label = uilabel(app.GridLayout2);
            app.StraightSectionLengthEditField_2Label.Text = 'Straight Section Length';
            app.StraightSectionLengthEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.StraightSectionLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @StraightSectionLengthEditField_2ValueChanged, true);
            app.FlameHolderHalfAngleEditField_2Label = uilabel(app.GridLayout2);
            app.FlameHolderHalfAngleEditField_2Label.Text = 'Flame Holder Half Angle';
            app.FlameHolderHalfAngleEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.FlameHolderHalfAngleEditField_2.ValueChangedFcn = createCallbackFcn(app, @FlameHolderHalfAngleEditField_2ValueChanged, true);
            app.FlameHolderHeightEditField_2Label = uilabel(app.GridLayout2);
            app.FlameHolderHeightEditField_2Label.Text = 'Flame Holder Height';
            app.FlameHolderHeightEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.FlameHolderHeightEditField_2.ValueChangedFcn = createCallbackFcn(app, @FlameHolderHeightEditField_2ValueChanged, true);
            app.NozzleDiameterEditFieldLabel = uilabel(app.GridLayout2);
            app.NozzleDiameterEditFieldLabel.Text = 'Nozzle Diameter';
            app.NozzleDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NozzleDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @NozzleDiameterEditFieldValueChanged, true);
            app.TurbineDiameterEditFieldLabel = uilabel(app.GridLayout2);
            app.TurbineDiameterEditFieldLabel.Text = 'Turbine Diameter';
            app.TurbineDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TurbineDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @TurbineDiameterEditFieldValueChanged, true);
            app.FuelFlowRateEditFieldLabel = uilabel(app.GridLayout2);
            app.FuelFlowRateEditFieldLabel.Text = 'Fuel Flow Rate';
            app.FuelFlowRateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FuelFlowRateEditField.ValueChangedFcn = createCallbackFcn(app, @FuelFlowRateEditFieldValueChanged, true);
            app.NumberofPlotPointsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofPlotPointsEditFieldLabel.Text = 'Number of Plot Points';
            app.NumberofPlotPointsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofPlotPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPlotPointsEditFieldValueChanged, true);
            app.JkgKLabel = uilabel(app.GridLayout2);
            app.JkgKLabel.Text = 'J/kg-K';
            app.kgm3Label = uilabel(app.GridLayout2);
            app.kgm3Label.Text = 'kg/m^3';
            app.CLabel = uilabel(app.GridLayout2);
            app.CLabel.Text = 'C';
            app.kmhrLabel = uilabel(app.GridLayout2);
            app.kmhrLabel.Text = 'km/hr';
            app.LsLabel = uilabel(app.GridLayout2);
            app.LsLabel.Text = 'L/s';
            app.mLabel = uilabel(app.GridLayout2);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GridLayout2);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.GridLayout2);
            app.mLabel_3.Text = 'm';
            app.degLabel = uilabel(app.GridLayout2);
            app.degLabel.Text = 'deg';
            app.mmLabel_3 = uilabel(app.GridLayout2);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_2 = uilabel(app.GridLayout2);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel = uilabel(app.GridLayout2);
            app.mmLabel.Text = 'mm';
            app.gminLabel = uilabel(app.GridLayout2);
            app.gminLabel.Text = 'g/min';
            app.MachNumberPostDiffuserEditField_2Label = uilabel(app.GridLayout2);
            app.MachNumberPostDiffuserEditField_2Label.Text = 'Mach Number Post Diffuser';
            app.MachNumberPostDiffuserEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.MachNumberPostDiffuserEditField_2.ValueChangedFcn = createCallbackFcn(app, @MachNumberPostDiffuserEditField_2ValueChanged, true);
            app.RunButton_2 = uibutton(app.MainCalculationsTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.SaveButton_2 = uibutton(app.MainCalculationsTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SaveFileNameEditField_2Label = uilabel(app.MainCalculationsTab);
            app.SaveFileNameEditField_2Label.Text = 'Save File Name';
            app.SaveFileNameEditField_2 = uieditfield(app.MainCalculationsTab, 'text');
            app.SaveFileNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @SaveFileNameEditField_2ValueChanged, true);
            app.ResetButton = uibutton(app.MainCalculationsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StopButton = uibutton(app.MainCalculationsTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CombustionAnalysisTab_2 = uitab(app.TabGroup);
            app.CombustionAnalysisTab_2.Title = 'Combustion Analysis';
            app.Image_5 = uiimage(app.CombustionAnalysisTab_2);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.CombustionParametersPanel = uipanel(app.CombustionAnalysisTab_2);
            app.CombustionParametersPanel.Title = 'Combustion Parameters';
            app.EngineCombustionPanel = uipanel(app.CombustionParametersPanel);
            app.EngineCombustionPanel.Title = 'Engine Combustion';
            app.GridLayout3 = uigridlayout(app.EngineCombustionPanel);
            app.FuelLabel = uilabel(app.GridLayout3);
            app.FuelLabel.Text = 'Fuel';
            app.AfterburnerCombustionPanel = uipanel(app.CombustionParametersPanel);
            app.AfterburnerCombustionPanel.Title = 'Afterburner Combustion';
            app.GridLayout3_2 = uigridlayout(app.AfterburnerCombustionPanel);
            app.MolecularWeight1EditField_2Label = uilabel(app.GridLayout3_2);
            app.MolecularWeight1EditField_2Label.Text = 'Molecular Weight 1';
            app.MolecularWeight1EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.MolecularWeight2EditField_2Label = uilabel(app.GridLayout3_2);
            app.MolecularWeight2EditField_2Label.Text = 'Molecular Weight 2';
            app.MolecularWeight2EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.MolecularWeight3EditField_2Label = uilabel(app.GridLayout3_2);
            app.MolecularWeight3EditField_2Label.Text = 'Molecular Weight 3';
            app.MolecularWeight3EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.MolecularWeight4EditField_2Label = uilabel(app.GridLayout3_2);
            app.MolecularWeight4EditField_2Label.Text = 'Molecular Weight 4';
            app.MolecularWeight4EditField_2 = uieditfield(app.GridLayout3_2, 'numeric');
            app.Element1EditField_2Label = uilabel(app.GridLayout3_2);
            app.Element1EditField_2Label.Text = 'Element 1';
            app.Element1EditField_2 = uieditfield(app.GridLayout3_2, 'text');
            app.Element2EditField_2Label = uilabel(app.GridLayout3_2);
            app.Element2EditField_2Label.Text = 'Element 2';
            app.Element2EditField_2 = uieditfield(app.GridLayout3_2, 'text');
            app.Element3EditField_2Label = uilabel(app.GridLayout3_2);
            app.Element3EditField_2Label.Text = 'Element 3';
            app.Element3EditField_2 = uieditfield(app.GridLayout3_2, 'text');
            app.Element4EditField_2Label = uilabel(app.GridLayout3_2);
            app.Element4EditField_2Label.Text = 'Element 4';
            app.Element4EditField_2 = uieditfield(app.GridLayout3_2, 'text');
            app.CombustionResultsPanel = uipanel(app.CombustionAnalysisTab_2);
            app.CombustionResultsPanel.Title = 'Combustion Results';
            app.GridLayout4 = uigridlayout(app.CombustionResultsPanel);
            app.AfterburnerTemperatureEditFieldLabel = uilabel(app.GridLayout4);
            app.AfterburnerTemperatureEditFieldLabel.Text = 'Afterburner Temperature';
            app.AfterburnerTemperatureEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AfterburnerTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @AfterburnerTemperatureEditFieldValueChanged, true);
            app.AfterburnerPressureEditFieldLabel = uilabel(app.GridLayout4);
            app.AfterburnerPressureEditFieldLabel.Text = 'Afterburner Pressure';
            app.AfterburnerPressureEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AfterburnerPressureEditField.ValueChangedFcn = createCallbackFcn(app, @AfterburnerPressureEditFieldValueChanged, true);
            app.PhiLabel = uilabel(app.GridLayout4);
            app.PhiLabel.Text = 'Phi';
            app.PhiEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
            app.f_actualEditFieldLabel = uilabel(app.GridLayout4);
            app.f_actualEditFieldLabel.Text = 'f_actual';
            app.f_actualEditField = uieditfield(app.GridLayout4, 'numeric');
            app.f_actualEditField.ValueChangedFcn = createCallbackFcn(app, @f_actualEditFieldValueChanged, true);
            app.f_stoichiometricEditFieldLabel = uilabel(app.GridLayout4);
            app.f_stoichiometricEditFieldLabel.Text = 'f_stoichiometric';
            app.f_stoichiometricEditField = uieditfield(app.GridLayout4, 'numeric');
            app.f_stoichiometricEditField.ValueChangedFcn = createCallbackFcn(app, @f_stoichiometricEditFieldValueChanged, true);
            app.KLabel = uilabel(app.GridLayout4);
            app.KLabel.Text = 'K';
            app.kPaLabel = uilabel(app.GridLayout4);
            app.kPaLabel.Text = 'kPa';
            app.EvaluateButton = uibutton(app.CombustionAnalysisTab_2, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.FinalResultsTab = uitab(app.TabGroup);
            app.FinalResultsTab.Title = 'Final Results';
            app.UIAxes3 = uiaxes(app.FinalResultsTab);
            title(app.UIAxes3, 'Afterburner Geometry', 'Interpreter', 'latex')
            xlabel(app.UIAxes3, 'Axial Distance (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes3, 'Transverse Distance (m)', 'Interpreter', 'latex')
            zlabel(app.UIAxes3, 'Z', 'Interpreter', 'latex')
            app.UIAxes4 = uiaxes(app.FinalResultsTab);
            title(app.UIAxes4, 'Flow Parameters', 'Interpreter', 'latex')
            xlabel(app.UIAxes4, 'Axial Distance (m)', 'Interpreter', 'latex')
            ylabel(app.UIAxes4, 'Y', 'Interpreter', 'latex')
            zlabel(app.UIAxes4, 'Z', 'Interpreter', 'latex')
            app.Image_3 = uiimage(app.FinalResultsTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.UnitConversionsTab = uitab(app.TabGroup);
            app.UnitConversionsTab.Title = 'Unit Conversions';
            app.Image_6 = uiimage(app.UnitConversionsTab);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Image_7 = uiimage(app.SettingsTab);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.ThemeDropDownLabel = uilabel(app.SettingsTab);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.SettingsTab);
            app.ThemeDropDown.Items = {'Light Mode', 'Dark Mode'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Value = 'Light Mode';
            app.PlotColorPanel = uipanel(app.SettingsTab);
            app.PlotColorPanel.Title = 'Plot Color';
            app.GridLayout5 = uigridlayout(app.PlotColorPanel);
            app.EngineDropDownLabel = uilabel(app.GridLayout5);
            app.EngineDropDownLabel.Text = 'Engine';
            app.EngineDropDown = uidropdown(app.GridLayout5);
            app.EngineDropDown.Items = {'Black', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Red', 'White'};
            app.EngineDropDown.ValueChangedFcn = createCallbackFcn(app, @EngineDropDownValueChanged, true);
            app.EngineDropDown.Value = 'Red';
            app.DiffuserDropDownLabel = uilabel(app.GridLayout5);
            app.DiffuserDropDownLabel.Text = 'Diffuser ';
            app.DiffuserDropDown = uidropdown(app.GridLayout5);
            app.DiffuserDropDown.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Black', 'White'};
            app.DiffuserDropDown.Value = 'Red';
            app.StraightSectionDropDownLabel = uilabel(app.GridLayout5);
            app.StraightSectionDropDownLabel.Text = 'Straight Section';
            app.StraightSectionDropDown = uidropdown(app.GridLayout5);
            app.StraightSectionDropDown.Items = {'Magenta', 'Green', 'Blue', 'Cyan', 'Red', 'Yellow', 'Black', 'White'};
            app.StraightSectionDropDown.Value = 'Red';
            app.CombustionChamberDropDownLabel = uilabel(app.GridLayout5);
            app.CombustionChamberDropDownLabel.Text = 'Combustion Chamber';
            app.CombustionChamberDropDown = uidropdown(app.GridLayout5);
            app.CombustionChamberDropDown.Items = {'Blue', 'Green', 'Red', 'Cyan', 'Magenta', 'Yellow', 'Black', 'White'};
            app.CombustionChamberDropDown.Value = 'Red';
            app.FlameHolderDropDownLabel = uilabel(app.GridLayout5);
            app.FlameHolderDropDownLabel.Text = 'Flame Holder';
            app.FlameHolderDropDown = uidropdown(app.GridLayout5);
            app.FlameHolderDropDown.Items = {'Green', 'Red', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Black', 'White'};
            app.FlameHolderDropDown.Value = 'Red';
            app.NozzleDropDownLabel = uilabel(app.GridLayout5);
            app.NozzleDropDownLabel.Text = 'Nozzle';
            app.NozzleDropDown = uidropdown(app.GridLayout5);
            app.NozzleDropDown.Items = {'Cyan', 'Green', 'Blue', 'Red', 'Magenta', 'Yellow', 'Black', 'White'};
            app.NozzleDropDown.Value = 'Red';
            app.JankButton = uibutton(app.SettingsTab, 'push');
            app.JankButton.ButtonPushedFcn = createCallbackFcn(app, @JankButtonPushed, true);
            app.JankButton.Text = 'Jank';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ignition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end